classdef Exam2Cope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AdeeptArmUIFigure     matlab.ui.Figure
        SpecialMovePanel      matlab.ui.container.Panel
        BeginButton           matlab.ui.control.Button
        Set3Button_2          matlab.ui.control.Button
        Set2Button_2          matlab.ui.control.Button
        Set1Button_2          matlab.ui.control.Button
        SetPointsPanel        matlab.ui.container.Panel
        GoTo3Button           matlab.ui.control.Button
        Set3Button            matlab.ui.control.Button
        SavedPosition3Text    matlab.ui.control.Label
        GoTo2Button           matlab.ui.control.Button
        Set2Button            matlab.ui.control.Button
        SavedPosition2Text    matlab.ui.control.Label
        GoTo1Button           matlab.ui.control.Button
        Set1Button            matlab.ui.control.Button
        SavedPosition1Text    matlab.ui.control.Label
        NumericValuesPanel    matlab.ui.container.Panel
        ResetButton           matlab.ui.control.Button
        GoButton              matlab.ui.control.Button
        Joint3EditField       matlab.ui.control.NumericEditField
        Joint3EditFieldLabel  matlab.ui.control.Label
        Joint2EditField       matlab.ui.control.NumericEditField
        Joint2EditFieldLabel  matlab.ui.control.Label
        Joint1EditField       matlab.ui.control.NumericEditField
        Joint1EditFieldLabel  matlab.ui.control.Label
        JointSlidersPanel     matlab.ui.container.Panel
        Joint3Slider          matlab.ui.control.Slider
        Joint3SliderLabel     matlab.ui.control.Label
        Joint2Slider          matlab.ui.control.Slider
        Joint2SliderLabel     matlab.ui.control.Label
        Joint1Slider          matlab.ui.control.Slider
        Joint1SliderLabel     matlab.ui.control.Label
        axes_arm              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        jointAngles = [0, 0, 0]
        link1Patch
        link1Vertices
        link2Patch
        link2Vertices
        link3Patch
        link3Vertices
        savedPosition1Angles = [0, 0, 0]
        savedPosition2Angles = [0, 0, 0]
        savedPosition3Angles = [0, 0, 0]
        savedSpecialPositionAngles1 = [0, 0, 0]
        savedSpecialPositionAngles2 = [0, 0, 0]
        savedSpecialPositionAngles3 = [0, 0, 0]
        ToolTransform 
        
    end
    
    methods (Access = private)
        
        function updateSliders(app)
            app.Joint1Slider.Value = app.jointAngles(1);
            app.Joint2Slider.Value = app.jointAngles(2);
            app.Joint3Slider.Value = app.jointAngles(3);
        end
        
        function updateEditFields(app)
            app.Joint1EditField.Value = app.jointAngles(1);
            app.Joint2EditField.Value = app.jointAngles(2);
            app.Joint3EditField.Value = app.jointAngles(3);
        end
        
        function updateArm(app)
 
            A1 = create_A_matrix(70,  -6,   0, app.jointAngles(1));
            A2 = create_A_matrix(0,    5, -90, app.jointAngles(2));
            A3 = create_A_matrix(0,   83.5,   0, app.jointAngles(3));


            T0_1 = A1;
            T0_2 = A1 * A2;
            T0_3 = A1 * A2 * A3;

            link1VerticesWRTground = T0_1 * app.link1Vertices;
            link2VerticesWRTground = T0_2 * app.link2Vertices;
            link3VerticesWRTground = T0_3 * app.link3Vertices;

            set(app.link1Patch,'Vertices', link1VerticesWRTground(1:3,:)');
            set(app.link2Patch,'Vertices', link2VerticesWRTground(1:3,:)');
            set(app.link3Patch,'Vertices', link3VerticesWRTground(1:3,:)');
        end
        
        function moveToPosition(app, angle1, angle2, angle3)
            app.setAngle(1, angle1);
            app.setAngle(2, angle2);
            app.setAngle(3, angle3);
        end

        function setAngle(app, joint, angle)
            app.jointAngles(joint) = angle;
            app.updateArm();
            app.updateSliders();
            app.updateEditFields();
            disp(['Setting joint ', num2str(joint), ' to angle ', num2str(angle)]);
        end
        
        function animateMovement(app, startAngles, endAngles, numSteps, pauseTime)
            % Interpolate between each joint angle
            theta1Vals = linspace(startAngles(1), endAngles(1), numSteps);
            theta2Vals = linspace(startAngles(2), endAngles(2), numSteps);
            theta3Vals = linspace(startAngles(3), endAngles(3), numSteps);
            
            for i = 1:numSteps
                app.moveToPosition(theta1Vals(i), theta2Vals(i), theta3Vals(i));
                pause(pauseTime); % Short pause for smoothness
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc

            % Prepare the axes_arm properties
            app.axes_arm.View = [90 -50];
            app.axes_arm.Visible = 'off';

            % Once you are in Matlab App Designer you can try these axes_arm properties too.
            % These properties keep the Axes from automatically resizing (optional)
%             app.axes_arm.XLim = [-160 250];
%             app.axes_arm.YLim = [-250 250];
%             app.axes_arm.ZLim = [-22 22];

            % Create vertices for all the patches
            makeStlLink("Exam2Part1.stl", app.axes_arm, [.2 .2 .2]);  % Doesn't move. No need to save a handle to it.
            % Save references to the vertices of each patch, make points 4x1 not 3x1.
            app.link1Patch = makeStlLink("Exam2Part2.stl", app.axes_arm, [112/255 198/255 210/255]);
            app.link1Vertices = get(app.link1Patch, 'Vertices')';
            app.link1Vertices(4,:) = ones(1, size(app.link1Vertices,2));

            app.link2Patch = makeStlLink("Exam2Part3.stl", app.axes_arm, [198/255 172/255 20/255]);
            app.link2Vertices = get(app.link2Patch, 'Vertices')';
            app.link2Vertices(4,:) = ones(1, size(app.link2Vertices,2));

            app.link3Patch = makeStlLink("Exam2Part4.stl", app.axes_arm, [181/255 24/255 35/255]);
            app.link3Vertices = get(app.link3Patch, 'Vertices')';
            app.link3Vertices(4,:) = ones(1, size(app.link3Vertices,2));
            
            app.ToolTransform = hgtransform('Parent', app.axes_arm);
            set(app.link3Patch, 'Parent', app.ToolTransform);
            
            app.updateArm();
            
            app.updateSliders();
            
            app.updateEditFields();
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(1) = changingValue;
            app.updateArm();
            app.updateEditFields();
        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(2) = changingValue;
            app.updateArm();
            app.updateEditFields();
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue = event.Value;
            app.jointAngles(3) = changingValue;
            app.updateArm();
            app.updateEditFields();
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            app.jointAngles(1) = app.Joint1EditField.Value;
            app.jointAngles(2) = app.Joint2EditField.Value;
            app.jointAngles(3) = app.Joint3EditField.Value;
            app.updateArm();
            app.updateSliders();
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.jointAngles = [0, 0, 0];
            app.updateArm();
            app.updateSliders();
            app.updateEditFields();
        end

        % Button pushed function: Set1Button
        function Set1ButtonPushed(app, event)
            app.savedPosition1Angles = app.jointAngles;
            app.SavedPosition1Text.Text = sprintf("%0.1f %0.1f %0.1f", app.savedPosition1Angles);
        end

        % Button pushed function: GoTo1Button
        function GoTo1ButtonPushed(app, event)
            app.jointAngles = app.savedPosition1Angles;
            app.updateArm();
            app.updateSliders();
            app.updateEditFields();
        end

        % Button pushed function: Set2Button
        function Set2ButtonPushed(app, event)
            app.savedPosition2Angles = app.jointAngles;
            app.SavedPosition2Text.Text = sprintf("%0.1f %0.1f %0.1f", app.savedPosition2Angles);
        end

        % Button pushed function: GoTo2Button
        function GoTo2ButtonPushed(app, event)
            app.jointAngles = app.savedPosition2Angles;
            app.updateArm();
            app.updateSliders();
            app.updateEditFields();
        end

        % Button pushed function: Set3Button
        function Set3ButtonPushed(app, event)
            app.savedPosition3Angles = app.jointAngles;
            app.SavedPosition3Text.Text = sprintf("%0.1f %0.1f %0.1f", app.savedPosition3Angles);
        end

        % Button pushed function: GoTo3Button
        function GoTo3ButtonPushed(app, event)
            app.jointAngles = app.savedPosition3Angles;
            app.updateArm();
            app.updateSliders();
            app.updateEditFields();
        end

        % Button pushed function: Set1Button_2
        function Set1Button_2Pushed(app, event)
            app.savedSpecialPositionAngles1 = app.jointAngles;
        end

        % Button pushed function: Set2Button_2
        function Set2Button_2Pushed(app, event)
            app.savedSpecialPositionAngles2 = app.jointAngles;
        end

        % Button pushed function: Set3Button_2
        function Set3Button_2Pushed(app, event)
            app.savedSpecialPositionAngles3 = app.jointAngles;
        end

        % Button pushed function: BeginButton
        function BeginButtonPushed(app, event)
            % Define the number of interpolation steps
              numSteps = 50; % Higher = smoother movement
              pauseTime = 0.01; % Time between steps
               
             app.moveToPosition(app.savedSpecialPositionAngles1(1), app.savedSpecialPositionAngles1(2), app.savedSpecialPositionAngles1(3));
             pause(0.3);      
             
             animateMovement(app, app.savedSpecialPositionAngles1, app.savedSpecialPositionAngles2, numSteps, pauseTime);
             pause(0.3);
             
             animateMovement(app, app.savedSpecialPositionAngles2, app.savedSpecialPositionAngles3, numSteps, pauseTime);
             pause(0.3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AdeeptArmUIFigure = uifigure('Visible', 'off');
            app.AdeeptArmUIFigure.Name = 'Adeept Arm';
            app.axes_arm = uiaxes(app.AdeeptArmUIFigure);
            xlabel(app.axes_arm, 'X')
            ylabel(app.axes_arm, 'Y')
            zlabel(app.axes_arm, 'Z')
            app.JointSlidersPanel = uipanel(app.AdeeptArmUIFigure);
            app.JointSlidersPanel.Title = 'Joint Sliders';
            app.Joint1SliderLabel = uilabel(app.JointSlidersPanel);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.JointSlidersPanel);
            app.Joint1Slider.Limits = [-90 90];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2SliderLabel = uilabel(app.JointSlidersPanel);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.JointSlidersPanel);
            app.Joint2Slider.Limits = [-135 90];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint3SliderLabel = uilabel(app.JointSlidersPanel);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.JointSlidersPanel);
            app.Joint3Slider.Limits = [-90 90];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.NumericValuesPanel = uipanel(app.AdeeptArmUIFigure);
            app.NumericValuesPanel.Title = 'Numeric Values';
            app.Joint1EditFieldLabel = uilabel(app.NumericValuesPanel);
            app.Joint1EditFieldLabel.Text = 'Joint 1';
            app.Joint1EditField = uieditfield(app.NumericValuesPanel, 'numeric');
            app.Joint2EditFieldLabel = uilabel(app.NumericValuesPanel);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.Joint2EditField = uieditfield(app.NumericValuesPanel, 'numeric');
            app.Joint3EditFieldLabel = uilabel(app.NumericValuesPanel);
            app.Joint3EditFieldLabel.Text = 'Joint 3';
            app.Joint3EditField = uieditfield(app.NumericValuesPanel, 'numeric');
            app.GoButton = uibutton(app.NumericValuesPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.ResetButton = uibutton(app.NumericValuesPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SetPointsPanel = uipanel(app.AdeeptArmUIFigure);
            app.SetPointsPanel.Title = 'Set Points';
            app.SavedPosition1Text = uilabel(app.SetPointsPanel);
            app.SavedPosition1Text.Text = '--';
            app.Set1Button = uibutton(app.SetPointsPanel, 'push');
            app.Set1Button.ButtonPushedFcn = createCallbackFcn(app, @Set1ButtonPushed, true);
            app.Set1Button.Text = 'Set 1';
            app.GoTo1Button = uibutton(app.SetPointsPanel, 'push');
            app.GoTo1Button.ButtonPushedFcn = createCallbackFcn(app, @GoTo1ButtonPushed, true);
            app.GoTo1Button.Text = 'Go To 1';
            app.SavedPosition2Text = uilabel(app.SetPointsPanel);
            app.SavedPosition2Text.Text = '--';
            app.Set2Button = uibutton(app.SetPointsPanel, 'push');
            app.Set2Button.ButtonPushedFcn = createCallbackFcn(app, @Set2ButtonPushed, true);
            app.Set2Button.Text = 'Set 2';
            app.GoTo2Button = uibutton(app.SetPointsPanel, 'push');
            app.GoTo2Button.ButtonPushedFcn = createCallbackFcn(app, @GoTo2ButtonPushed, true);
            app.GoTo2Button.Text = 'Go To 2';
            app.SavedPosition3Text = uilabel(app.SetPointsPanel);
            app.SavedPosition3Text.Text = '--';
            app.Set3Button = uibutton(app.SetPointsPanel, 'push');
            app.Set3Button.ButtonPushedFcn = createCallbackFcn(app, @Set3ButtonPushed, true);
            app.Set3Button.Text = 'Set 3';
            app.GoTo3Button = uibutton(app.SetPointsPanel, 'push');
            app.GoTo3Button.ButtonPushedFcn = createCallbackFcn(app, @GoTo3ButtonPushed, true);
            app.GoTo3Button.Text = 'Go To 3';
            app.SpecialMovePanel = uipanel(app.AdeeptArmUIFigure);
            app.SpecialMovePanel.Title = 'Special Move';
            app.Set1Button_2 = uibutton(app.SpecialMovePanel, 'push');
            app.Set1Button_2.ButtonPushedFcn = createCallbackFcn(app, @Set1Button_2Pushed, true);
            app.Set1Button_2.Text = 'Set 1';
            app.Set2Button_2 = uibutton(app.SpecialMovePanel, 'push');
            app.Set2Button_2.ButtonPushedFcn = createCallbackFcn(app, @Set2Button_2Pushed, true);
            app.Set2Button_2.Text = 'Set 2';
            app.Set3Button_2 = uibutton(app.SpecialMovePanel, 'push');
            app.Set3Button_2.ButtonPushedFcn = createCallbackFcn(app, @Set3Button_2Pushed, true);
            app.Set3Button_2.Text = 'Set 3';
            app.BeginButton = uibutton(app.SpecialMovePanel, 'push');
            app.BeginButton.ButtonPushedFcn = createCallbackFcn(app, @BeginButtonPushed, true);
            app.BeginButton.Text = 'Begin';
            % Show the figure after all components are created
            app.AdeeptArmUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exam2Cope

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AdeeptArmUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AdeeptArmUIFigure)
        end
    end
end