classdef exam1MATLAB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ServerResponseLabel     matlab.ui.control.Label
        PeriodmsEditField       matlab.ui.control.NumericEditField
        PeriodmsEditFieldLabel  matlab.ui.control.Label
        FlashesEditField        matlab.ui.control.NumericEditField
        FlashesEditFieldLabel   matlab.ui.control.Label
        FlashButton             matlab.ui.control.Button
        LEDOffButton            matlab.ui.control.Button
        LEDOnButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        arduinoObj
    end
    
    methods (Access = private)
        function response = sendCommand(app, command)
            import matlab.net.*
            import matlab.net.http.*
        
            if isstring(command)
                command = char(command);
            end
        
            uri = URI("http://137.112.217.195:8080/api/" + command);
        
            command = strrep(command, '%20', ' ');
            
            r = RequestMessage;
        
            try
                resp = send(r, uri);
        
                if resp.StatusCode == matlab.net.http.StatusCode.OK
                    response = char(resp.Body.Data);
                else
                    response = sprintf("Error: %s", resp.StatusLine);
                end
        
                fprintf("Response to '%s' --> %s\n", command, response);
            catch e
                response = "Failed to send request: " + e.message;
                fprintf("Error sending '%s': %s\n", command, e.message);
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             try
%                 app.arduinoObj = serialport("COM3", 19200); 
%                 configureTerminator(app.arduinoObj, "LF");
%                 flush(app.arduinoObj);
%             catch e
%                 uialert(app.UIFigure, "Failed to connect to Arduino: " + e.message, "Connection Error");
%             end
        end

        % Button pushed function: LEDOffButton
        function LEDOffButtonPushed(app, event)
            response = app.sendCommand("LED OFF");
            app.ServerResponseLabel.Text = response;  
        end

        % Button pushed function: LEDOnButton
        function LEDOnButtonPushed(app, event)
            response = app.sendCommand("LED ON");
            app.ServerResponseLabel.Text = response;
        end

        % Button pushed function: FlashButton
        function FlashButtonPushed(app, event)
            count = app.FlashesEditField.Value;
            delay = app.PeriodmsEditField.Value;
            
            if count <= 0 || delay < 0
                app.ServerResponseLabel.Text = "Invalid input.";
                return;
            end
            
            cmd = sprintf("FLASH %d %d", count, delay);  % Encode spaces with %%
            response = app.sendCommand(cmd);
            app.ServerResponseLabel.Text = response;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LEDOnButton = uibutton(app.UIFigure, 'push');
            app.LEDOnButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOnButtonPushed, true);
            app.LEDOnButton.Text = 'LED On';
            app.LEDOffButton = uibutton(app.UIFigure, 'push');
            app.LEDOffButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOffButtonPushed, true);
            app.LEDOffButton.Text = 'LED Off';
            app.FlashButton = uibutton(app.UIFigure, 'push');
            app.FlashButton.ButtonPushedFcn = createCallbackFcn(app, @FlashButtonPushed, true);
            app.FlashButton.Text = 'Flash';
            app.FlashesEditFieldLabel = uilabel(app.UIFigure);
            app.FlashesEditFieldLabel.Text = 'Flashes';
            app.FlashesEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeriodmsEditFieldLabel = uilabel(app.UIFigure);
            app.PeriodmsEditFieldLabel.Text = 'Period (ms)';
            app.PeriodmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ServerResponseLabel = uilabel(app.UIFigure);
            app.ServerResponseLabel.Text = 'Server Response';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exam1MATLAB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end