classdef Exam3MQTT2024Cope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        DistancecmLabel      matlab.ui.control.Label
        RightBackwardButton  matlab.ui.control.Button
        RightForwardButton   matlab.ui.control.Button
        BothBackwardButton   matlab.ui.control.Button
        BothForwardButton    matlab.ui.control.Button
        LeftBackwardButton   matlab.ui.control.Button
        LeftForwardButton    matlab.ui.control.Button
        RightSlider          matlab.ui.control.Slider
        RightSliderLabel     matlab.ui.control.Label
        LeftSlider           matlab.ui.control.Slider
        LeftSliderLabel      matlab.ui.control.Label
        TimeSlider           matlab.ui.control.Slider
        TimeSliderLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqClient
    end
    
    methods (Access = private)
        
        function sendMessage(app, messageType, payload)
            messageStruct.type = messageType;
            messageStruct.payload = payload;
            message = jsonencode(messageStruct);
            fprintf("Sending: %s\n", message);
            app.mqClient.write("me435/copejh/to_pi", message);
        end
        
        function myCallback(app, topic, message)
            fprintf("Received: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type;
            if isfield(messageStruct, "type") && strcmp(messageStruct.type, "ultra")
                if isfield(messageStruct, "payload")
                    distance = messageStruct.payload;
                    app.DistancecmLabel.Text = sprintf("Distance = %.1f cm", distance);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://broker.hivemq.com");
            app.mqClient.subscribe("me435/copejh/#", "Callback", @app.myCallback)
        end

        % Button pushed function: LeftForwardButton
        function LeftForwardButtonPushed(app, event)
            time = app.TimeSlider.Value;
            speed = round(app.LeftSlider.Value);
            app.sendMessage("left_forward", [time speed]);
        end

        % Button pushed function: LeftBackwardButton
        function LeftBackwardButtonPushed(app, event)
            time = app.TimeSlider.Value;
            speed = round(app.LeftSlider.Value);
            app.sendMessage("left_backward", [time speed]);
        end

        % Button pushed function: BothForwardButton
        function BothForwardButtonPushed(app, event)
            time = app.TimeSlider.Value;
            speed1 = round(app.LeftSlider.Value);
            speed2 = round(app.RightSlider.Value);
            app.sendMessage("both_forward", [time speed1 speed2]);
        end

        % Button pushed function: BothBackwardButton
        function BothBackwardButtonPushed(app, event)
            time = app.TimeSlider.Value;
            speed1 = round(app.LeftSlider.Value);
            speed2 = round(app.RightSlider.Value);
            app.sendMessage("both_backward", [time speed1 speed2]);
        end

        % Button pushed function: RightForwardButton
        function RightForwardButtonPushed(app, event)
            time = app.TimeSlider.Value;
            speed = round(app.RightSlider.Value);
            app.sendMessage("right_forward", [time speed]);
        end

        % Button pushed function: RightBackwardButton
        function RightBackwardButtonPushed(app, event)
            time = app.TimeSlider.Value;
            speed = round(app.RightSlider.Value);
            app.sendMessage("right_backward", [time speed]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.Limits = [0.5 4];
            app.TimeSlider.Value = 1;
            app.LeftSliderLabel = uilabel(app.UIFigure);
            app.LeftSliderLabel.Text = 'Left';
            app.LeftSlider = uislider(app.UIFigure);
            app.LeftSlider.Value = 40;
            app.RightSliderLabel = uilabel(app.UIFigure);
            app.RightSliderLabel.Text = 'Right';
            app.RightSlider = uislider(app.UIFigure);
            app.RightSlider.Value = 40;
            app.LeftForwardButton = uibutton(app.UIFigure, 'push');
            app.LeftForwardButton.ButtonPushedFcn = createCallbackFcn(app, @LeftForwardButtonPushed, true);
            app.LeftForwardButton.Text = 'Left Forward';
            app.LeftBackwardButton = uibutton(app.UIFigure, 'push');
            app.LeftBackwardButton.ButtonPushedFcn = createCallbackFcn(app, @LeftBackwardButtonPushed, true);
            app.LeftBackwardButton.Text = 'Left Backward';
            app.BothForwardButton = uibutton(app.UIFigure, 'push');
            app.BothForwardButton.ButtonPushedFcn = createCallbackFcn(app, @BothForwardButtonPushed, true);
            app.BothForwardButton.Text = 'Both Forward';
            app.BothBackwardButton = uibutton(app.UIFigure, 'push');
            app.BothBackwardButton.ButtonPushedFcn = createCallbackFcn(app, @BothBackwardButtonPushed, true);
            app.BothBackwardButton.Text = 'Both Backward';
            app.RightForwardButton = uibutton(app.UIFigure, 'push');
            app.RightForwardButton.ButtonPushedFcn = createCallbackFcn(app, @RightForwardButtonPushed, true);
            app.RightForwardButton.Text = 'Right Forward';
            app.RightBackwardButton = uibutton(app.UIFigure, 'push');
            app.RightBackwardButton.ButtonPushedFcn = createCallbackFcn(app, @RightBackwardButtonPushed, true);
            app.RightBackwardButton.Text = 'Right Backward';
            app.DistancecmLabel = uilabel(app.UIFigure);
            app.DistancecmLabel.Text = 'Distance = 0 cm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Exam3MQTT2024Cope

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end