classdef Car_MQTT_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Lab5MQTTGUIUIFigure  matlab.ui.Figure
        MiscPanel            matlab.ui.container.Panel
        BeepButton           matlab.ui.control.Button
        ServoHeadPanel       matlab.ui.container.Panel
        TiltSlider           matlab.ui.control.Slider
        TiltSliderLabel      matlab.ui.control.Label
        PanSlider            matlab.ui.control.Slider
        PanSliderLabel       matlab.ui.control.Label
        SpeedSlider          matlab.ui.control.Slider
        SpeedSliderLabel     matlab.ui.control.Label
        ClickintheareabelowandusekeyboardkeystodriveLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqClient
        priorKey = "";
    end
    
    methods (Access = private)
        
        function sendMessage(app, messageType, payload)
           messageStruct.type = messageType;
           if nargin > 2
               messageStruct.payload = payload;
           end
           messageStruct.payload = payload;
           message = jsonencode(messageStruct);
           fprintf("Sending: %s\n", message);
           app.mqClient.write("me435/copejh/to_pi", message);
       end
        
        function myCallback(app, topic, message)
            fprintf("Received: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type;

            if messageType == "reset"
                    app.counter = 0;
                    app.CounterLabel.Text = num2str(app.counter);
            end

        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            app.mqClient = mqttclient("tcp://test.mosquitto.org");
            app.mqClient.subscribe("me435/copejh/#", "Callback", @app.myCallback)
            
        end

        % Key press function: Lab5MQTTGUIUIFigure
        function Lab5MQTTGUIUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key, app.priorKey)
                return
            end
            app.priorKey = key;
            fprintf("Send motor command\n")
            
            speed = round(app.SpeedSlider.Value);
            
            if strcmp(key, "uparrow")
                app.sendMessage("set_speeds", [speed speed speed speed])
            end
            if strcmp(key, "downarrow")
                app.sendMessage("set_speeds", [-speed -speed -speed -speed])
            end
            if strcmp(key, "leftarrow")
                app.sendMessage("set_speeds", [-speed speed speed -speed])
            end
            if strcmp(key, "rightarrow")
                app.sendMessage("set_speeds", [speed -speed -speed speed])
            end
            if strcmp(key, "a")
                app.sendMessage("set_speeds", [-turnSpeed -turnSpeed turnSpeed turnSpeed])
            end
            if strcmp(key, "d")
                app.sendMessage("set_speeds", [turnSpeed turnSpeed -turnSpeed -turnSpeed])
            end
        end

        % Key release function: Lab5MQTTGUIUIFigure
        function Lab5MQTTGUIUIFigureKeyRelease(app, event)
            fprintf("Send stop\n")
            app.priorKey = "";
            app.sendMessage("stop");
        end

        % Value changing function: PanSlider
        function PanSliderValueChanging(app, event)
            panValue = round(event.Value);
            app.sendMessage("pan", panValue);
        end

        % Value changing function: TiltSlider
        function TiltSliderValueChanging(app, event)
            tiltValue = round(event.Value);
            app.sendMessage("tilt", tiltValue)            
        end

        % Button pushed function: BeepButton
        function BeepButtonPushed(app, event)
            app.sendMessage("beep")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Lab5MQTTGUIUIFigure = uifigure('Visible', 'off');
            app.Lab5MQTTGUIUIFigure.Name = 'MATLAB App';
            app.Lab5MQTTGUIUIFigure.KeyPressFcn = createCallbackFcn(app, @Lab5MQTTGUIUIFigureKeyPress, true);
            app.Lab5MQTTGUIUIFigure.KeyReleaseFcn = createCallbackFcn(app, @Lab5MQTTGUIUIFigureKeyRelease, true);
            app.ClickintheareabelowandusekeyboardkeystodriveLabel = uilabel(app.Lab5MQTTGUIUIFigure);
            app.ClickintheareabelowandusekeyboardkeystodriveLabel.Text = 'Click in the area below and use keyboard keys to drive';
            app.SpeedSliderLabel = uilabel(app.Lab5MQTTGUIUIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.Lab5MQTTGUIUIFigure);
            app.ServoHeadPanel = uipanel(app.Lab5MQTTGUIUIFigure);
            app.ServoHeadPanel.Title = 'Servo Head';
            app.PanSliderLabel = uilabel(app.ServoHeadPanel);
            app.PanSliderLabel.Text = 'Pan';
            app.PanSlider = uislider(app.ServoHeadPanel);
            app.PanSlider.Limits = [20 160];
            app.PanSlider.ValueChangingFcn = createCallbackFcn(app, @PanSliderValueChanging, true);
            app.PanSlider.Value = 20;
            app.TiltSliderLabel = uilabel(app.ServoHeadPanel);
            app.TiltSliderLabel.Text = 'Tilt';
            app.TiltSlider = uislider(app.ServoHeadPanel);
            app.TiltSlider.Limits = [70 160];
            app.TiltSlider.ValueChangingFcn = createCallbackFcn(app, @TiltSliderValueChanging, true);
            app.TiltSlider.Value = 70;
            app.MiscPanel = uipanel(app.Lab5MQTTGUIUIFigure);
            app.MiscPanel.Title = 'Misc';
            app.BeepButton = uibutton(app.MiscPanel, 'push');
            app.BeepButton.ButtonPushedFcn = createCallbackFcn(app, @BeepButtonPushed, true);
            app.BeepButton.Text = 'Beep';
            % Show the figure after all components are created
            app.Lab5MQTTGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Car_MQTT_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Lab5MQTTGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Lab5MQTTGUIUIFigure)
        end
    end
end