classdef mgtt_gpio_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MGTTGPIOGUIUIFigure  matlab.ui.Figure
        GreenSwitch          matlab.ui.control.ToggleSwitch
        GreenSwitchLabel     matlab.ui.control.Label
        YellowSwitch         matlab.ui.control.ToggleSwitch
        YellowSwitchLabel    matlab.ui.control.Label
        RedSwitch            matlab.ui.control.ToggleSwitch
        RedSwitchLabel       matlab.ui.control.Label
        CounterLabel         matlab.ui.control.Label
        SendLEDsButton       matlab.ui.control.Button
        GreenOffButton       matlab.ui.control.Button
        GreenOnButton        matlab.ui.control.Button
        YellowOffButton      matlab.ui.control.Button
        YellowOnButton       matlab.ui.control.Button
        RedOffButton         matlab.ui.control.Button
        RedOnButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        mqClient
        counter = 0
    end
    
    methods (Access = private)
        
        function sendMessage(app, messageType, payload)
           messageStruct.type = messageType;
           messageStruct.payload = payload;
           message = jsonencode(messageStruct);
           fprintf("Sending: %s\n", message);
           app.mqClient.write("fisherds", message);
       end
        
        function myCallback(app, topic, message)
            fprintf("Received: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type

            if messageType == "button"
                    app.counter = app.counter + 1;
                    app.CounterLabel.Text = num2str(app.counter);
            end
            if messageType == "reset"
                    app.counter = 0;
                    app.CounterLabel.Text = num2str(app.counter);
            end

        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           clc
           mqttclient("tcp://test.mosquitto.org");
           subscribe(app.mqClient, "fisherds", "Callback", @app.myCallback)

        end

        % Button pushed function: RedOnButton
        function RedOnButtonPushed(app, event)
            app.sendMessage("red", "on");
        end

        % Button pushed function: RedOffButton
        function RedOffButtonPushed(app, event)
            app.sendMessage("red", "off");
        end

        % Button pushed function: YellowOnButton
        function YellowOnButtonPushed(app, event)
            app.sendMessage("yellow", "1");
        end

        % Button pushed function: YellowOffButton
        function YellowOffButtonPushed(app, event)
            app.sendMessage("yellow", "0");
        end

        % Button pushed function: GreenOnButton
        function GreenOnButtonPushed(app, event)
            app.sendMessage("green", "true");
        end

        % Button pushed function: GreenOffButton
        function GreenOffButtonPushed(app, event)
            app.sendMessage("green", "false");
        end

        % Button pushed function: SendLEDsButton
        function SendLEDsButtonPushed(app, event)
            redValue = str2num(app.RedSwitch.Value);
            yellowValue = str2num(app.YellowSwitch.Value);
            greenValue = str2num(app.GreenSwitch.Value);
            ledValues = [redValue, yellowValue, greenValue]
            app.sendMessage("leds", ledValues)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MGTTGPIOGUIUIFigure = uifigure('Visible', 'off');
            app.MGTTGPIOGUIUIFigure.Name = 'MGTT GPIO GUI';
            app.RedOnButton = uibutton(app.MGTTGPIOGUIUIFigure, 'push');
            app.RedOnButton.ButtonPushedFcn = createCallbackFcn(app, @RedOnButtonPushed, true);
            app.RedOnButton.Text = 'Red On';
            app.RedOffButton = uibutton(app.MGTTGPIOGUIUIFigure, 'push');
            app.RedOffButton.ButtonPushedFcn = createCallbackFcn(app, @RedOffButtonPushed, true);
            app.RedOffButton.Text = 'Red Off';
            app.YellowOnButton = uibutton(app.MGTTGPIOGUIUIFigure, 'push');
            app.YellowOnButton.ButtonPushedFcn = createCallbackFcn(app, @YellowOnButtonPushed, true);
            app.YellowOnButton.Text = 'Yellow On';
            app.YellowOffButton = uibutton(app.MGTTGPIOGUIUIFigure, 'push');
            app.YellowOffButton.ButtonPushedFcn = createCallbackFcn(app, @YellowOffButtonPushed, true);
            app.YellowOffButton.Text = 'Yellow Off';
            app.GreenOnButton = uibutton(app.MGTTGPIOGUIUIFigure, 'push');
            app.GreenOnButton.ButtonPushedFcn = createCallbackFcn(app, @GreenOnButtonPushed, true);
            app.GreenOnButton.Text = 'Green On';
            app.GreenOffButton = uibutton(app.MGTTGPIOGUIUIFigure, 'push');
            app.GreenOffButton.ButtonPushedFcn = createCallbackFcn(app, @GreenOffButtonPushed, true);
            app.GreenOffButton.Text = 'Green Off';
            app.SendLEDsButton = uibutton(app.MGTTGPIOGUIUIFigure, 'push');
            app.SendLEDsButton.ButtonPushedFcn = createCallbackFcn(app, @SendLEDsButtonPushed, true);
            app.SendLEDsButton.Text = 'Send LEDs';
            app.CounterLabel = uilabel(app.MGTTGPIOGUIUIFigure);
            app.CounterLabel.Text = '0';
            app.RedSwitchLabel = uilabel(app.MGTTGPIOGUIUIFigure);
            app.RedSwitchLabel.Text = 'Red';
            app.RedSwitch = uiswitch(app.MGTTGPIOGUIUIFigure, 'toggle');
            app.RedSwitch.Items = {'0', '1'};
            app.RedSwitch.Value = '0';
            app.YellowSwitchLabel = uilabel(app.MGTTGPIOGUIUIFigure);
            app.YellowSwitchLabel.Text = 'Yellow';
            app.YellowSwitch = uiswitch(app.MGTTGPIOGUIUIFigure, 'toggle');
            app.YellowSwitch.Items = {'0', '1'};
            app.YellowSwitch.Value = '0';
            app.GreenSwitchLabel = uilabel(app.MGTTGPIOGUIUIFigure);
            app.GreenSwitchLabel.Text = 'Green';
            app.GreenSwitch = uiswitch(app.MGTTGPIOGUIUIFigure, 'toggle');
            app.GreenSwitch.Items = {'0', '1'};
            app.GreenSwitch.Value = '0';
            % Show the figure after all components are created
            app.MGTTGPIOGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mgtt_gpio_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MGTTGPIOGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MGTTGPIOGUIUIFigure)
        end
    end
end